classdef control_loop_visualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ControlLoopAppUIFigure          matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        SelectionTab                    matlab.ui.container.Tab
        SelectionGridLayout             matlab.ui.container.GridLayout
        BeginAutomaticSimulationButton  matlab.ui.control.Button
        AutoRefSigSpinner               matlab.ui.control.Spinner
        BeginManualSimulationButton     matlab.ui.control.Button
        AutomaticStartText              matlab.ui.control.Label
        ManualRefSigSpinner             matlab.ui.control.Spinner
        ManualStartText                 matlab.ui.control.Label
        ButtonGridLayout                matlab.ui.container.GridLayout
        Fav1Button                      matlab.ui.control.Button
        Fav2Button                      matlab.ui.control.Button
        Fav3Button                      matlab.ui.control.Button
        RANDOMButton                    matlab.ui.control.Button
        ControlsTab                     matlab.ui.container.Tab
        ControlsGridLayout              matlab.ui.container.GridLayout
        PositionSlider                  matlab.ui.control.Slider
        VelocitySlider                  matlab.ui.control.Slider
        PositionSpinner                 matlab.ui.control.Spinner
        VelocitySpinner                 matlab.ui.control.Spinner
        PositionText                    matlab.ui.control.EditField
        VelocityText                    matlab.ui.control.EditField
        RightPanel                      matlab.ui.container.Panel
        GraphGridLayout                 matlab.ui.container.GridLayout
        PositionUIAxes                  matlab.ui.control.UIAxes
        VelocityUIAxes                  matlab.ui.control.UIAxes
        MuscleUIAxes                    matlab.ui.control.UIAxes
        GridLayout2                     matlab.ui.container.GridLayout
        MSEEditFieldLabel               matlab.ui.control.Label
        Pos_MSEEditField                matlab.ui.control.NumericEditField
        MinErrordegLabel_3              matlab.ui.control.Label
        Pos_RangeLowerEditField         matlab.ui.control.NumericEditField
        MaxErrordegLabel                matlab.ui.control.Label
        Pos_RangeUpperEditField         matlab.ui.control.NumericEditField
        NRMSEEditFieldLabel             matlab.ui.control.Label
        Pos_NRMSEEditField              matlab.ui.control.NumericEditField
        GridLayout3                     matlab.ui.container.GridLayout
        MSEEditField_2Label             matlab.ui.control.Label
        Vel_MSEEditField                matlab.ui.control.NumericEditField
        MinErrordegLabel_2              matlab.ui.control.Label
        Vel_RangeLowerEditField         matlab.ui.control.NumericEditField
        MaxErrordegLabel_2              matlab.ui.control.Label
        Vel_RangeUpperEditField         matlab.ui.control.NumericEditField
        NRMSEEditFieldLabel_2           matlab.ui.control.Label
        Vel_NRMSEEditField              matlab.ui.control.NumericEditField
        GridLayout4                     matlab.ui.container.GridLayout
        MSEEditFieldLabel_2             matlab.ui.control.Label
        Mus_MSEEditField                matlab.ui.control.NumericEditField
        MinErrordegLabel                matlab.ui.control.Label
        Mus_RangeLowerEditField         matlab.ui.control.NumericEditField
        MaxErrordegLabel_3              matlab.ui.control.Label
        Mus_RangeUpperEditField         matlab.ui.control.NumericEditField
        NRMSEEditFieldLabel_3           matlab.ui.control.Label
        Mus_NRMSEEditField              matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        ds = -1;
        
        refSignal; % Reference signal
        ti = 0;
        Ts = 0.001;
        tf = 10;
        
        manualSimulation = true;
        startSimulation = false; % Indicator to start Control Loop script
        
        inputChanged = false; % Whether the sliders/spinners were used
        last_ctrl_pos = 60;
        ctrl_pos = 60
        ctrl_vel = 0;
        vel_lowerbound = -250;
        vel_upperbound = 250;
        
    end
    
    properties (Access = public)
        autoRefValue = 1;
        manualRefValue = 60;
        
        PositionGraph_r % Position reference
        PositionGraph_y % Position output        
        PositionGraph_e % Position error
        PositionGraph_ysim % Position simulation
        VelocityGraph_r % Velocity reference
        VelocityGraph_y % Velocity output
        VelocityGraph_e % Velocity error
        VelocityGraph_ysim % Velocity simulation
        MuscleGraph_u % Muscle command signals
    end
    
    methods (Access = public)
        
        % 
        function signal = convertMotToSignal(app, signal_value)
            filestring = "C:/Users/Jaxton/OneDrive/controlled-movement/Control System/input_signals/sig_" + num2str(signal_value) + ".mot";
            infile = fopen(filestring, 'r');
            c = textscan(infile, '%f%f%f', 'delimiter', '\n', 'HeaderLines', 14);
            fclose(infile);
            
            pos = transpose(c{3});
            vel = [0 diff(pos)/0.001];
            signal.t = transpose(c{1});
            signal.data = [pos; vel];
            signal.ti = signal.t(1);
            signal.tf = signal.t(end);
            signal.Ts = 0.001;
        end
        
        % 
        function setReferenceSignal(app, type, signal_value)
            if (strcmp(type, 'Automatic')) % Get signal properties from predefined .mat file
                
                if (signal_value >=1 && signal_value <= 10)
                    % Find file with number
                    filestring = "C:/Users/Jaxton/OneDrive/controlled-movement/Control System/input_signals/sig_" + num2str(signal_value) + ".mat";
                    signal = open(filestring).signal;
                end
                
                if (signal_value > 700)
                    signal = app.convertMotToSignal(signal_value);
                end
                
                % Set properties
                app.refSignal = signal.data; % Signal file data is in degrees
                app.ti = signal.ti;
                app.Ts = signal.Ts;
                app.tf = signal.tf;
                
            elseif (strcmp(type, 'Manual')) % Set signal manually to be controlled with a starting position of signal value
                t = app.ti:app.Ts:app.tf;
                
                app.refSignal = repmat([signal_value; 0], 1, numel(t)); % Signal is already in radians
                
            else
                error("CRIT_ERR: Undefined State! Fix immediately!")
                
            end
            
            pos = app.refSignal(1,1);
            vel = app.refSignal(2,1);
            
            app.last_ctrl_pos = pos;
            app.ctrl_pos = pos;
            app.ctrl_vel = vel;
            
            app.updateRefControls(pos, vel);
            
        end
        
        % Update the graphs with new points
        function updateGraphs(app, graphdata)
            % Update Position Graph
            addpoints(app.PositionGraph_r, graphdata.t, graphdata.r(1,:)); % Position Reference
            addpoints(app.PositionGraph_y, graphdata.t, graphdata.y(1,:)); % Position Output
            addpoints(app.PositionGraph_e, graphdata.t, graphdata.e(1,:)); % Position Error
            %addpoints(app.PositionGraph_ysim, graphdata.t, graphdata.ysim(1,:)); % Position Simulation
            
            % Update Velocity Graph
            addpoints(app.VelocityGraph_r, graphdata.t, graphdata.r(2,:)); % Velocity Reference
            addpoints(app.VelocityGraph_y, graphdata.t, graphdata.y(2,:)); % Velocity Output
            addpoints(app.VelocityGraph_e, graphdata.t, graphdata.e(2,:)); % Velocity Error
            %addpoints(app.VelocityGraph_ysim, graphdata.t, graphdata.ysim(2,:)); % Velocity Simulation
            
            % Update Muscle Graph
            for i=1:8
                addpoints(app.MuscleGraph_u{i}, graphdata.t, graphdata.u(i,:)); % Muscle Command Signals
            end
            
            drawnow
            
            
            error = graphdata.y_full - graphdata.r_full;
            
            % Update MSE values next to position and velocity graphs
            app.Pos_MSEEditField.Value = norm(error(1,:)).^2 / numel(graphdata.y_full(1,:));
            app.Pos_NRMSEEditField.Value = norm(-error(1,:)) / norm(graphdata.r_full(1,:) - mean(graphdata.r_full(1,:)));
            app.Pos_RangeLowerEditField.Value = min(error(1,:));
            app.Pos_RangeUpperEditField.Value = max(error(1,:));
            
            app.Vel_MSEEditField.Value = norm(error(2,:)).^2 / numel(graphdata.y_full(2,:));
            app.Vel_NRMSEEditField.Value = norm(-error(2,:)) / norm(graphdata.r_full(2,:) - mean(graphdata.r_full(2,:)));
            app.Vel_RangeLowerEditField.Value = min(error(2,:));
            app.Vel_RangeUpperEditField.Value = max(error(2,:));
        end
        
        % 
        function updateRefControls(app, pos, vel)
            app.PositionSlider.Value = min(max(pos, 0), 180); % Change the position slider value
            app.PositionSpinner.Value = min(max(pos, 0), 180); % Change the position spinner value
            
            app.VelocitySlider.Value = min(max(vel, -250), 250); % Change the velocity slider value
            app.VelocitySpinner.Value = min(max(vel, -250), 250); % Change the velocity spinner value
        end
        
        % 
        function [r_rad, r_deg] = updateRef(app, r_deg)
            % Code holds the true reference signal so use that as default.
            % If manual simulation, override that signal with the reference controls
            
            % If manual simulation, use values from sliders/spinners
            if (app.manualSimulation && app.inputChanged)
                r_deg = [app.ctrl_pos; app.ctrl_vel];
            end
            
            r_rad = deg2rad(r_deg);
            
            % Update values of controls
            app.updateRefControls(r_deg(1), r_deg(2));
            
            app.inputChanged = false;
        end
        
        % 
        function [r_rad, r_deg, ti, tf] = getRefSignal(app)
            r_rad = deg2rad(app.refSignal);
            r_deg = app.refSignal;
            ti = app.ti;
            tf = app.tf;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ds)
            app.ds;
            
            % Make sure we start at the selection tab
            app.TabGroup.SelectedTab = app.SelectionTab;
            
            % Create animated lines in graphs
            app.PositionGraph_r = animatedline(app.PositionUIAxes, NaN, NaN, 'Color', '#0072BD');
            app.PositionGraph_y = animatedline(app.PositionUIAxes, NaN, NaN, 'Color', '#D95319');
            app.PositionGraph_e = animatedline(app.PositionUIAxes, NaN, NaN, 'Color', '#EDB120');
            %app.PositionGraph_ysim = animatedline(app.PositionUIAxes, NaN, NaN, 'Color', '#7E2F8E');
            yline(app.PositionUIAxes, 0,'k--')
            leg1 = legend(app.PositionUIAxes, 'Reference', 'Output', 'Error');
            
            app.VelocityGraph_r = animatedline(app.VelocityUIAxes, NaN, NaN, 'Color', '#0072BD');
            app.VelocityGraph_y = animatedline(app.VelocityUIAxes, NaN, NaN, 'Color', '#D95319');
            app.VelocityGraph_e = animatedline(app.VelocityUIAxes, NaN, NaN, 'Color', '#EDB120');
            %app.VelocityGraph_ysim = animatedline(app.VelocityUIAxes, NaN, NaN, 'Color', '#7E2F8E');
            yline(app.VelocityUIAxes, 0,'k--')
            leg2 = legend(app.VelocityUIAxes, 'Reference', 'Output', 'Error');
            
            colors = [colororder; 220/255 23/255 220/255];
            for i=1:8
                app.MuscleGraph_u{i} = animatedline(app.MuscleUIAxes, NaN, NaN, 'Color', colors(i,:));
            end
            yline(app.MuscleUIAxes, 0,'k--')
            leg3 = legend(app.MuscleUIAxes, 'TRI Long', 'TRI Lat', 'TRI Med', 'BIC Long', 'BIC Short', 'BRA', 'Shoulder Res', 'Elbow Res');
            
            %%% Graph formatting %%%
            fontsize = 9; % 9 is default
            
            % Set legend location
            set(leg1, 'Location', 'south', 'Orientation', 'horizontal', 'FontSize', fontsize);
            set(leg2, 'Location', 'south', 'Orientation', 'horizontal', 'FontSize', fontsize);
            set(leg3, 'Location', 'south', 'Orientation', 'vertical', 'NumColumns', 4, 'FontSize', fontsize);
            
            % Set legend icon so they're not large [Line Dot]
            leg1.ItemTokenSize = [10, 10];
            leg2.ItemTokenSize = [10, 10];
            leg3.ItemTokenSize = [10, 10];
            
        end

        % Value changing function: PositionSlider
        function PositionSliderValueChanging(app, event)
            changingValue = event.Value;
            
            if (app.manualSimulation)
                app.last_ctrl_pos = app.ctrl_pos;
                app.ctrl_pos = changingValue;
                
                vel_value = (app.ctrl_pos - app.last_ctrl_pos) / app.Ts; % Calculate velocity
                app.ctrl_vel = min(max(vel_value, app.vel_lowerbound), app.vel_upperbound); % Bound the velocity value
                app.inputChanged = true;
                
                % Change the position spinner value
                app.PositionSpinner.Value = changingValue;
            else
                app.PositionSlider.Value = app.ctrl_pos; % TODO: Is this correct? Will this have problems?
            end
            
        end

        % Value changed function: PositionSlider
        function PositionSliderValueChanged(app, event)
            value = app.PositionSlider.Value;
            
            if (app.manualSimulation)
                app.last_ctrl_pos = app.ctrl_pos;
                app.ctrl_pos = value;
                
                vel_value = (app.ctrl_pos - app.last_ctrl_pos) / app.Ts; % Calculate velocity
                app.ctrl_vel = min(max(vel_value, app.vel_lowerbound), app.vel_upperbound); % Bound the velocity value
                app.inputChanged = true;
                
                % Change the position spinner value
                app.PositionSpinner.Value = value;
            else
                app.PositionSlider.Value = app.ctrl_pos; % TODO: Is this correct? Will this have problems?
            end
            
        end

        % Value changing function: VelocitySlider
        function VelocitySliderValueChanging(app, event)
            changingValue = event.Value;
            
            if (app.manualSimulation)
                app.ctrl_vel = changingValue;
                app.inputChanged = true;
                
                % Change the velocity spinner value
                app.VelocitySpinner.Value = changingValue;
            else
                app.VelocitySlider.Value = app.ctrl_vel;
            end

        end

        % Value changed function: VelocitySlider
        function VelocitySliderValueChanged(app, event)
            value = app.VelocitySlider.Value;
            
            if (app.manualSimulation)
                app.ctrl_vel = value;
                app.inputChanged = true;
                
                % Change the velocity spinner value
                app.VelocitySpinner.Value = value;
            else
                app.VelocitySlider.Value = app.ctrl_vel;
            end
            
        end

        % Value changed function: PositionSpinner
        function PositionSpinnerValueChanged(app, event)
            value = app.PositionSpinner.Value;
            
            if (app.manualSimulation)
                app.ctrl_vel = value;
                app.inputChanged = true;
                
                % Change the velocity spinner value
                app.VelocitySlider.Value = value;
            else
                app.VelocitySpinner.Value = app.ctrl_vel;
            end
            
        end

        % Value changed function: VelocitySpinner
        function VelocitySpinnerValueChanged(app, event)
            value = app.VelocitySpinner.Value;
            
            if (app.manualSimulation)
                app.ctrl_vel = value;
                app.inputChanged = true;
                
                % Change the velocity spinner value
                app.VelocitySlider.Value = value;
            else
                app.VelocitySpinner.Value = app.ctrl_vel;
            end
            
        end

        % Button pushed function: BeginAutomaticSimulationButton
        function BeginAutomaticSimulationButtonPushed(app, event)
            if app.startSimulation == true
                return
            end
            
            app.manualSimulation = false;
            
            % Beginning an automatic simulation with the selected reference signal from the reference signal spinner
            % Includes to updateControls
            app.setReferenceSignal('Automatic', app.autoRefValue);
            
            % Change over to the controls tab
            app.TabGroup.SelectedTab = app.ControlsTab;
            
            % Indicate to Control Loop script to start
            app.startSimulation = true;
            uiresume(app.ControlLoopAppUIFigure);
        end

        % Button pushed function: BeginManualSimulationButton
        function BeginManualSimulationButtonPushed(app, event)
            if app.startSimulation == true
                return
            end
            
            app.manualSimulation = true;
            
            % Beginning a manual simulation with the selected reference signal from the reference signal spinner
            % Includes to updateControls
            app.setReferenceSignal('Manual', app.manualRefValue);
            
            % Change over to the controls tab
            app.TabGroup.SelectedTab = app.ControlsTab;
            
            % Indicate to Control Loop script to start
            app.startSimulation = true;
            uiresume(app.ControlLoopAppUIFigure);
        end

        % Value changed function: AutoRefSigSpinner
        function AutoRefSigSpinnerValueChanged(app, event)
            value = app.AutoRefSigSpinner.Value;
            app.autoRefValue = value;
        end

        % Value changed function: ManualRefSigSpinner
        function ManualRefSigSpinnerValueChanged(app, event)
            value = app.ManualRefSigSpinner.Value;
            app.manualRefValue = value;
        end

        % Button pushed function: Fav1Button
        function Fav1ButtonPushed(app, event)
            if app.startSimulation == true
                return
            end
            
            app.manualSimulation = false;
            
            % Beginning an automatic simulation with the selected reference signal from the reference signal spinner
            % Includes to updateControls
            app.setReferenceSignal('Automatic', 777);
            
            % Change over to the controls tab
            app.TabGroup.SelectedTab = app.ControlsTab;
            
            % Indicate to Control Loop script to start
            app.startSimulation = true;
            uiresume(app.ControlLoopAppUIFigure);
        end

        % Button pushed function: Fav2Button
        function Fav2ButtonPushed(app, event)
            if app.startSimulation == true
                return
            end
            
            app.manualSimulation = false;
            
            % Beginning an automatic simulation with the selected reference signal from the reference signal spinner
            % Includes to updateControls
            app.setReferenceSignal('Automatic', 778);
            
            % Change over to the controls tab
            app.TabGroup.SelectedTab = app.ControlsTab;
            
            % Indicate to Control Loop script to start
            app.startSimulation = true;
            uiresume(app.ControlLoopAppUIFigure);
        end

        % Button pushed function: Fav3Button
        function Fav3ButtonPushed(app, event)
            if app.startSimulation == true
                return
            end
            
            app.manualSimulation = false;
            
            % Beginning an automatic simulation with the selected reference signal from the reference signal spinner
            % Includes to updateControls
            app.setReferenceSignal('Automatic', 779);
            
            % Change over to the controls tab
            app.TabGroup.SelectedTab = app.ControlsTab;
            
            % Indicate to Control Loop script to start
            app.startSimulation = true;
            uiresume(app.ControlLoopAppUIFigure);
        end

        % Button pushed function: RANDOMButton
        function RANDOMButtonPushed(app, event)
            % Need to implement logic to make a new random signal
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ControlLoopAppUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {799, 799};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {321, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ControlLoopAppUIFigure = uifigure('Visible', 'off');
            app.ControlLoopAppUIFigure.Name = 'Control Loop App';
            app.ControlLoopAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ControlLoopAppUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.SelectionTab = uitab(app.TabGroup);
            app.SelectionTab.Title = 'Selection';
            app.SelectionGridLayout = uigridlayout(app.SelectionTab);
            app.BeginAutomaticSimulationButton = uibutton(app.SelectionGridLayout, 'push');
            app.BeginAutomaticSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @BeginAutomaticSimulationButtonPushed, true);
            app.BeginAutomaticSimulationButton.Text = 'Begin Automatic Simulation';
            app.AutoRefSigSpinner = uispinner(app.SelectionGridLayout);
            app.AutoRefSigSpinner.Limits = [1 10];
            app.AutoRefSigSpinner.ValueChangedFcn = createCallbackFcn(app, @AutoRefSigSpinnerValueChanged, true);
            app.AutoRefSigSpinner.Value = 1;
            app.BeginManualSimulationButton = uibutton(app.SelectionGridLayout, 'push');
            app.BeginManualSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @BeginManualSimulationButtonPushed, true);
            app.BeginManualSimulationButton.Text = 'Begin Manual Simulation';
            app.AutomaticStartText = uilabel(app.SelectionGridLayout);
            app.AutomaticStartText.Text = {'AUTOMATIC SIMULATION'; 'REFERENCE SIGNAL SELECTOR'; 'ED. 2 V. 2.31'};
            app.ManualRefSigSpinner = uispinner(app.SelectionGridLayout);
            app.ManualRefSigSpinner.Limits = [0 180];
            app.ManualRefSigSpinner.ValueChangedFcn = createCallbackFcn(app, @ManualRefSigSpinnerValueChanged, true);
            app.ManualRefSigSpinner.Value = 60;
            app.ManualStartText = uilabel(app.SelectionGridLayout);
            app.ManualStartText.Text = {'MANUAL SIMULATION'; 'REFERENCE SIGNAL SELECTOR'; 'ED. 19 V. 3.18'};
            app.ButtonGridLayout = uigridlayout(app.SelectionGridLayout);
            app.Fav1Button = uibutton(app.ButtonGridLayout, 'push');
            app.Fav1Button.ButtonPushedFcn = createCallbackFcn(app, @Fav1ButtonPushed, true);
            app.Fav1Button.Text = 'Fav 1';
            app.Fav2Button = uibutton(app.ButtonGridLayout, 'push');
            app.Fav2Button.ButtonPushedFcn = createCallbackFcn(app, @Fav2ButtonPushed, true);
            app.Fav2Button.Text = 'Fav 2';
            app.Fav3Button = uibutton(app.ButtonGridLayout, 'push');
            app.Fav3Button.ButtonPushedFcn = createCallbackFcn(app, @Fav3ButtonPushed, true);
            app.Fav3Button.Text = 'Fav 3';
            app.RANDOMButton = uibutton(app.ButtonGridLayout, 'push');
            app.RANDOMButton.ButtonPushedFcn = createCallbackFcn(app, @RANDOMButtonPushed, true);
            app.RANDOMButton.Text = 'RANDOM';
            app.ControlsTab = uitab(app.TabGroup);
            app.ControlsTab.Title = 'Controls';
            app.ControlsGridLayout = uigridlayout(app.ControlsTab);
            app.PositionSlider = uislider(app.ControlsGridLayout);
            app.PositionSlider.Limits = [0 180];
            app.PositionSlider.ValueChangedFcn = createCallbackFcn(app, @PositionSliderValueChanged, true);
            app.PositionSlider.ValueChangingFcn = createCallbackFcn(app, @PositionSliderValueChanging, true);
            app.PositionSlider.Value = 60;
            app.VelocitySlider = uislider(app.ControlsGridLayout);
            app.VelocitySlider.Limits = [-250 250];
            app.VelocitySlider.ValueChangedFcn = createCallbackFcn(app, @VelocitySliderValueChanged, true);
            app.VelocitySlider.ValueChangingFcn = createCallbackFcn(app, @VelocitySliderValueChanging, true);
            app.PositionSpinner = uispinner(app.ControlsGridLayout);
            app.PositionSpinner.Limits = [0 270];
            app.PositionSpinner.RoundFractionalValues = 'on';
            app.PositionSpinner.ValueChangedFcn = createCallbackFcn(app, @PositionSpinnerValueChanged, true);
            app.PositionSpinner.Value = 60;
            app.VelocitySpinner = uispinner(app.ControlsGridLayout);
            app.VelocitySpinner.Limits = [-250 250];
            app.VelocitySpinner.RoundFractionalValues = 'on';
            app.VelocitySpinner.ValueChangedFcn = createCallbackFcn(app, @VelocitySpinnerValueChanged, true);
            app.VelocitySpinner.Editable = 'off';
            app.PositionText = uieditfield(app.ControlsGridLayout, 'text');
            app.PositionText.Value = 'Position (deg)';
            app.VelocityText = uieditfield(app.ControlsGridLayout, 'text');
            app.VelocityText.Value = 'Velocity (deg/s)';
            app.RightPanel = uipanel(app.GridLayout);
            app.GraphGridLayout = uigridlayout(app.RightPanel);
            app.PositionUIAxes = uiaxes(app.GraphGridLayout);
            title(app.PositionUIAxes, '')
            xlabel(app.PositionUIAxes, 'Time (s)')
            ylabel(app.PositionUIAxes, 'Position (deg)')
            app.PositionUIAxes.XLim = [0 10];
            app.PositionUIAxes.YLim = [-30 210];
            app.PositionUIAxes.YTick = [-30 0 30 60 90 120 150 180 210];
            app.PositionUIAxes.YTickLabel = {'-30'; '0'; '30'; '60'; '90'; '120'; '150'; '180'; '210'};
            app.VelocityUIAxes = uiaxes(app.GraphGridLayout);
            title(app.VelocityUIAxes, '')
            xlabel(app.VelocityUIAxes, 'Time (s)')
            ylabel(app.VelocityUIAxes, 'Velocity (deg/s)')
            app.VelocityUIAxes.XLim = [0 10];
            app.VelocityUIAxes.YLim = [-250 250];
            app.MuscleUIAxes = uiaxes(app.GraphGridLayout);
            title(app.MuscleUIAxes, '')
            xlabel(app.MuscleUIAxes, 'Time (s)')
            ylabel(app.MuscleUIAxes, 'Muscle Excitations')
            app.MuscleUIAxes.XLim = [0 10];
            app.MuscleUIAxes.YLim = [-1 1];
            app.MuscleUIAxes.ZLim = [0 1];
            app.GridLayout2 = uigridlayout(app.GraphGridLayout);
            app.MSEEditFieldLabel = uilabel(app.GridLayout2);
            app.MSEEditFieldLabel.Text = 'MSE';
            app.Pos_MSEEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MinErrordegLabel_3 = uilabel(app.GridLayout2);
            app.MinErrordegLabel_3.Text = 'Min Error (deg)';
            app.Pos_RangeLowerEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaxErrordegLabel = uilabel(app.GridLayout2);
            app.MaxErrordegLabel.Text = 'Max Error (deg)';
            app.Pos_RangeUpperEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NRMSEEditFieldLabel = uilabel(app.GridLayout2);
            app.NRMSEEditFieldLabel.Text = 'NRMSE';
            app.Pos_NRMSEEditField = uieditfield(app.GridLayout2, 'numeric');
            app.GridLayout3 = uigridlayout(app.GraphGridLayout);
            app.MSEEditField_2Label = uilabel(app.GridLayout3);
            app.MSEEditField_2Label.Text = 'MSE';
            app.Vel_MSEEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MinErrordegLabel_2 = uilabel(app.GridLayout3);
            app.MinErrordegLabel_2.Text = 'Min Error (deg)';
            app.Vel_RangeLowerEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MaxErrordegLabel_2 = uilabel(app.GridLayout3);
            app.MaxErrordegLabel_2.Text = 'Max Error (deg)';
            app.Vel_RangeUpperEditField = uieditfield(app.GridLayout3, 'numeric');
            app.NRMSEEditFieldLabel_2 = uilabel(app.GridLayout3);
            app.NRMSEEditFieldLabel_2.Text = 'NRMSE';
            app.Vel_NRMSEEditField = uieditfield(app.GridLayout3, 'numeric');
            app.GridLayout4 = uigridlayout(app.GraphGridLayout);
            app.MSEEditFieldLabel_2 = uilabel(app.GridLayout4);
            app.MSEEditFieldLabel_2.Text = 'MSE';
            app.Mus_MSEEditField = uieditfield(app.GridLayout4, 'numeric');
            app.MinErrordegLabel = uilabel(app.GridLayout4);
            app.MinErrordegLabel.Text = 'Min Error (deg)';
            app.Mus_RangeLowerEditField = uieditfield(app.GridLayout4, 'numeric');
            app.MaxErrordegLabel_3 = uilabel(app.GridLayout4);
            app.MaxErrordegLabel_3.Text = 'Max Error (deg)';
            app.Mus_RangeUpperEditField = uieditfield(app.GridLayout4, 'numeric');
            app.NRMSEEditFieldLabel_3 = uilabel(app.GridLayout4);
            app.NRMSEEditFieldLabel_3.Text = 'NRMSE';
            app.Mus_NRMSEEditField = uieditfield(app.GridLayout4, 'numeric');
            % Show the figure after all components are created
            app.ControlLoopAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = control_loop_visualizer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ControlLoopAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ControlLoopAppUIFigure)
        end
    end
end